classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        LoadImageMenu    matlab.ui.container.Menu
        FromDiskMenu     matlab.ui.container.Menu
        Menu             matlab.ui.container.Menu
        CreditsMenu      matlab.ui.container.Menu
        ShowCreditsMenu  matlab.ui.container.Menu
        Label            matlab.ui.control.Label
        CompressButton   matlab.ui.control.Button
        QFSlider         matlab.ui.control.Slider
        QFSliderLabel    matlab.ui.control.Label
        compressedAxes   matlab.ui.control.UIAxes
        originalAxes     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        im
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: FromDiskMenu
        function loadImg(app, event)
            [file, path] =uigetfile({'*.jpg;*.png;*.gif','All Image Files'}); % gets path and fill
            % checks if you actually chose a file or hit cancel
            if(ischar(path))
                app.im = fullfile(path, file);
                imshow(imread(app.im), 'Parent', app.originalAxes);
            end
        end

        % Button pushed function: CompressButton
        function CompressButtonPushed(app, event)
            
            N = 8;
            qualFact = app.QFSlider.Value;
            ogImg = imread(app.im);
            %%resize file
            resizeX = size(ogImg,1);
            resizeY = size(ogImg,2);
            %pad img so its divisible by N
            if(mod(size(ogImg,1),N)~=0)
                resizeX = size(ogImg,1);
                ogImg(size(ogImg, 1) + 1:(N-mod(size(ogImg,1),N)) + size(ogImg,1), :, :) = 1;
            end
            if(mod(size(ogImg, 2), N) ~= 0)
                resizeY = size(ogImg, 2);
                ogImg(:, size(ogImg, 2) + 1:(N-mod(size(ogImg,2),N)) + size(ogImg,2), :) = 1;
            end 

            ycbImg = rgb2ycbcr(ogImg); %converts to ycbcr
            sampledImg = subsample(ycbImg);
            dctImg= dctTransform(sampledImg);
            %quantize
            quantImg = quantize(dctImg, qualFact);
            %%compressedImg = imgCompress(app, qualFact, dctImg);
            deQuantImg = dequantize(quantImg);
            
            idctImg = idctTransform(deQuantImg);
            idctImg = uint8(round(idctImg));
            rgbImg = ycbcr2rgb(idctImg);
            rgbImg = rgbImg(1:resizeX,:,:);
            outImg = rgbImg(:,1:resizeY,:);
            imshow(outImg, 'Parent', app.compressedAxes);
        end

        % Value changed function: QFSlider
        function QFSliderValueChanged(app, event)
            app.QFSlider.Value = round(app.QFSlider.Value);
            value = app.QFSlider.Value;
            strVal = num2str(value);
            app.Label.Text=strVal;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadImageMenu = uimenu(app.UIFigure);
            app.LoadImageMenu.Text = 'Load Image';
            app.FromDiskMenu = uimenu(app.LoadImageMenu);
            app.FromDiskMenu.MenuSelectedFcn = createCallbackFcn(app, @loadImg, true);
            app.FromDiskMenu.Text = 'From Disk';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Enable = 'off';
            app.Menu.Text = '|';
            app.CreditsMenu = uimenu(app.UIFigure);
            app.CreditsMenu.Text = 'Credits';
            app.ShowCreditsMenu = uimenu(app.CreditsMenu);
            app.ShowCreditsMenu.Checked = 'on';
            app.ShowCreditsMenu.Text = 'Show Credits';
            app.originalAxes = uiaxes(app.UIFigure);
            title(app.originalAxes, 'Original')
            app.originalAxes.XTick = [];
            app.originalAxes.YTick = [];
            app.compressedAxes = uiaxes(app.UIFigure);
            title(app.compressedAxes, 'Title')
            app.compressedAxes.XTick = [];
            app.compressedAxes.YTick = [];
            app.QFSliderLabel = uilabel(app.UIFigure);
            app.QFSliderLabel.Text = 'QF';
            app.QFSlider = uislider(app.UIFigure);
            app.QFSlider.ValueChangedFcn = createCallbackFcn(app, @QFSliderValueChanged, true);
            app.QFSlider.MinorTicks = [];
            app.CompressButton = uibutton(app.UIFigure, 'push');
            app.CompressButton.ButtonPushedFcn = createCallbackFcn(app, @CompressButtonPushed, true);
            app.CompressButton.Text = 'Compress';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end